classdef MultiPhaseRectificationforPumps_Calculator_GurhanOzkayar_2022 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        OutputPanel                  matlab.ui.container.Panel
        QavgEditFieldLabel           matlab.ui.control.Label
        QavgEditField                matlab.ui.control.NumericEditField
        QrmsEditFieldLabel           matlab.ui.control.Label
        QrmsEditField                matlab.ui.control.NumericEditField
        QmaxEditFieldLabel           matlab.ui.control.Label
        QmaxEditField                matlab.ui.control.NumericEditField
        RFEditFieldLabel             matlab.ui.control.Label
        RFEditField                  matlab.ui.control.NumericEditField
        InputPanel                   matlab.ui.container.Panel
        CalculateButton              matlab.ui.control.Button
        FlowrateulminEditFieldLabel  matlab.ui.control.Label
        FlowrateulminEditField       matlab.ui.control.NumericEditField
        PumpsEditFieldLabel          matlab.ui.control.Label
        PumpsEditField               matlab.ui.control.NumericEditField
        NOTESLabel                   matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        GrhanzkayarTUDelft2022Label  matlab.ui.control.Label
        MultiPhaseRectificationToolforMicropumpsLabel  matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        PlotPanel                    matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
n = app.PumpsEditField.Value;
f=1; %frequency [Hz]
a = app.FlowrateulminEditField.Value;
t=0:1:720; %time

phi=zeros(); %first value for phase
sum1=zeros(); %first value for sum

for i=1:n
    phi(i,:)= (i-1)*360/n; %phase shift in degrees (360°=2*pi)

    sum1=a*sind(f*t+phi); %calculation of sum with varying phases
    sum1(sum1<0)=0; %HWR part, negative values are converted into zero
    plot(app.UIAxes,t,sum1(i,:),'linewidth',2) %plotting each individual pump
    hold(app.UIAxes, 'on' )
    legend(app.UIAxes,"Pump with " + phi + "° phase shift",'FontSize',12,'NumColumns',5); %legends of each phase shifted pump

    out1=sum(sum1,1); %sum1 has n rows, they are converted into one row array as a summation
    out1_avg=mean(out1);
    out1_rms=rms(out1);
    out1_max=max(out1);
    ff_out1=out1_rms./out1_avg;
    rf_out1=real(sqrt((ff_out1).^2-1));
end

plot(app.UIAxes,t,out1,'k--','DisplayName','Sum','linewidth',3)
hold(app.UIAxes, 'off' )

title(app.UIAxes,"Multi-phase rectification with phase shifted parallel pumps, " + n + " pump(s)",'FontSize',24);
xlabel(app.UIAxes,'Phase (°)','FontSize',20);
ylabel(app.UIAxes,'Flow rate (\mul/min)','FontSize',20);
%set(gca,'FontSize',18)
xlim(app.UIAxes,[0 720]); %dependent on t value
ylim(app.UIAxes,[0 a+(a*n)/3]); %maximum y value is dependent on a and n values
grid (app.UIAxes,'on')
grid (app.UIAxes,'minor')


Qavg(n) = out1_avg
Qrms(n) = out1_rms
Qmax(n) = out1_max
RF(n)= rf_out1

title(app.UIAxes_2,'Qavg, Qrms, and RF_fl comparison for different rectification levels','FontSize',24)
xlabel(app.UIAxes_2,'Rectification level','FontSize',20)

yyaxis (app.UIAxes_2,'left')
plot(app.UIAxes_2,n,Qavg(n),'k-o',n,Qrms(n),'k-square',n,Qmax(n),'k-^','MarkerFaceColor','k','MarkerSize',8,'linewidth',1.5)

ylabel(app.UIAxes_2,'Flow Rate (\mul/min)','FontSize',20)
%set(gca,'FontSize',18)
xlim(app.UIAxes_2,[0 n+1]);
ylim(app.UIAxes_2,[0 a+(a*n)/3]);
hold(app.UIAxes_2, 'on' )
yyaxis (app.UIAxes_2,'right')
plot(app.UIAxes_2,n,RF(n),'r-v','MarkerFaceColor','r','MarkerSize',8,'linewidth',1.5)
ylabel (app.UIAxes_2,'Fluidic Ripple Factor','FontSize',20)
xlim(app.UIAxes_2,[0 n+1]);
ylim(app.UIAxes_2,[0 1.5]);
grid (app.UIAxes_2,'on')
grid (app.UIAxes_2,'minor')
left_color = [0 0 0];
right_color = [1 0 0];
% set(figure,'defaultAxesColorOrder',[left_color; right_color]);

hold(app.UIAxes_2, 'on' )
legend(app.UIAxes_2,{'Qavg', 'Qrms', 'Qmax', 'RFfl'},'Orientation','horizontal','Location','northwest','FontSize',18)
app.QavgEditField.Value = Qavg(n);
app.QrmsEditField.Value = Qrms(n);
app.QmaxEditField.Value = Qmax(n);
app.RFEditField.Value = RF(n);
clear phi

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {703, 703};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {398, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.OutputPanel = uipanel(app.LeftPanel);
            app.OutputPanel.Title = 'Output Panel';
            app.QavgEditFieldLabel = uilabel(app.OutputPanel);
            app.QavgEditFieldLabel.Text = 'Qavg';
            app.QavgEditField = uieditfield(app.OutputPanel, 'numeric');
            app.QrmsEditFieldLabel = uilabel(app.OutputPanel);
            app.QrmsEditFieldLabel.Text = 'Qrms';
            app.QrmsEditField = uieditfield(app.OutputPanel, 'numeric');
            app.QmaxEditFieldLabel = uilabel(app.OutputPanel);
            app.QmaxEditFieldLabel.Text = 'Qmax';
            app.QmaxEditField = uieditfield(app.OutputPanel, 'numeric');
            app.RFEditFieldLabel = uilabel(app.OutputPanel);
            app.RFEditFieldLabel.Text = 'RF';
            app.RFEditField = uieditfield(app.OutputPanel, 'numeric');
            app.InputPanel = uipanel(app.LeftPanel);
            app.InputPanel.Title = 'Input Panel';
            app.CalculateButton = uibutton(app.InputPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.FlowrateulminEditFieldLabel = uilabel(app.InputPanel);
            app.FlowrateulminEditFieldLabel.Text = 'Flow rate (ul/min)';
            app.FlowrateulminEditField = uieditfield(app.InputPanel, 'numeric');
            app.PumpsEditFieldLabel = uilabel(app.InputPanel);
            app.PumpsEditFieldLabel.Text = 'Pumps';
            app.PumpsEditField = uieditfield(app.InputPanel, 'numeric');
            app.NOTESLabel = uilabel(app.InputPanel);
            app.NOTESLabel.Text = 'NOTES:';
            app.Label = uilabel(app.InputPanel);
            app.Label.Text = {'1- Choose positive integers for pump amount.'; ''; '2- Flow rates should be the same for each phase calculation.'; ''; '3- For each calculation:'; '   '; '   a- Flow rate profile plot will be refreshed.'; ''; '   b- Second plot will be overwrited.'; ''};
            app.GrhanzkayarTUDelft2022Label = uilabel(app.LeftPanel);
            app.GrhanzkayarTUDelft2022Label.Text = 'Gürhan Özkayar, TU Delft, 2022';
            app.MultiPhaseRectificationToolforMicropumpsLabel = uilabel(app.LeftPanel);
            app.MultiPhaseRectificationToolforMicropumpsLabel.Text = 'Multi-Phase Rectification Tool for Micropumps';
            app.RightPanel = uipanel(app.GridLayout);
            app.PlotPanel = uipanel(app.RightPanel);
            app.PlotPanel.Title = 'Plot Panel';
            app.UIAxes = uiaxes(app.PlotPanel);
            title(app.UIAxes, 'Multi-phase rectification with phase shifted parallel pumps')
            xlabel(app.UIAxes, 'Phase (degrees)')
            ylabel(app.UIAxes, 'Flow Rate (ul/min)')
            app.UIAxes_2 = uiaxes(app.PlotPanel);
            title(app.UIAxes_2, 'Qavg, Qrms, and RFfl comparison for different rectification levels')
            xlabel(app.UIAxes_2, 'Rectification level')
            ylabel(app.UIAxes_2, 'Flow rate (ul/min)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MultiPhaseRectificationforPumps_Calculator_GurhanOzkayar_2022

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end